classdef tes_performance_calculator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure                   % TES Per...
        Panel                  matlab.ui.container.Panel          % Excel s...
        LabelDropDown          matlab.ui.control.Label            % Time Stamp
        DropDown1              matlab.ui.control.DropDown         % A, B, C...
        Label                  matlab.ui.control.Label            % Time (s...
        DropDown2              matlab.ui.control.DropDown         % A, B, C...
        Label2                 matlab.ui.control.Label            % T1 (°C)
        DropDown3              matlab.ui.control.DropDown         % A, B, C...
        Label3                 matlab.ui.control.Label            % T2 (°C)
        DropDown4              matlab.ui.control.DropDown         % A, B, C...
        Label4                 matlab.ui.control.Label            % T3 (°C)
        DropDown5              matlab.ui.control.DropDown         % A, B, C...
        Label5                 matlab.ui.control.Label            % TH (°C)
        DropDown6              matlab.ui.control.DropDown         % A, B, C...
        Label6                 matlab.ui.control.Label            % T4 (°C)
        DropDown7              matlab.ui.control.DropDown         % A, B, C...
        Label7                 matlab.ui.control.Label            % RH (%)
        DropDown8              matlab.ui.control.DropDown         % A, B, C...
        LabelNumericEditField5 matlab.ui.control.Label            % No. Ads...
        NumericEditField5      matlab.ui.control.NumericEditField % [-Inf Inf]
        LabelEditField8        matlab.ui.control.Label            % Cycle T...
        EditField8             matlab.ui.control.EditField       
        Panel2                 matlab.ui.container.Panel          % Input p...
        Label8                 matlab.ui.control.Label            % Inlet R...
        NumericEditField2      matlab.ui.control.NumericEditField % [-Inf Inf]
        LabelEditField         matlab.ui.control.Label            % Excel file
        FilePathField          matlab.ui.control.EditField       
        Button                 matlab.ui.control.Button           % Browse...
        Label14                matlab.ui.control.Label            % Volume ...
        NumericEditField3      matlab.ui.control.NumericEditField % [-Inf Inf]
        Label15                matlab.ui.control.Label            % Pressur...
        NumericEditField4      matlab.ui.control.NumericEditField % [-Inf Inf]
        LabelDropDown13        matlab.ui.control.Label            % Column ...
        DropDown13             matlab.ui.control.DropDown         % 7.698×1...
        Label17                matlab.ui.control.Label            % Model o...
        DropDown14             matlab.ui.control.DropDown         % Old, New
        TabGroup               matlab.ui.container.TabGroup       % Perform...
        Tab                    matlab.ui.container.Tab            % Perform...
        DropDown9              matlab.ui.control.DropDown         % kWh/m³,...
        DropDown10             matlab.ui.control.DropDown         % kJ, kWh...
        DropDown11             matlab.ui.control.DropDown         % kg, g, lb
        LabelEditField2        matlab.ui.control.Label            % E.D.
        EditField2             matlab.ui.control.EditField       
        Label9                 matlab.ui.control.Label            % Heat
        EditField3             matlab.ui.control.EditField       
        Label10                matlab.ui.control.Label            % H₂O ads.
        EditField4             matlab.ui.control.EditField       
        Label16                matlab.ui.control.Label            % Ads. Cycle
        DropDown12             matlab.ui.control.DropDown         % 1, 2, 3...
        LabelListBox           matlab.ui.control.Label            % Data Set
        ListBox                matlab.ui.control.ListBox         
        Tab2                   matlab.ui.container.Tab            % Properties
        Label11                matlab.ui.control.Label            % Cp
        EditField5             matlab.ui.control.EditField       
        Label12                matlab.ui.control.Label            % Heat
        EditField6             matlab.ui.control.EditField       
        Label13                matlab.ui.control.Label            % H₂O ads.
        EditField7             matlab.ui.control.EditField       
        Button2                matlab.ui.control.Button           % Calculate
        TabGroup2              matlab.ui.container.TabGroup       % RH, Tem...
        Tab3                   matlab.ui.container.Tab            % RH
        UIAxes                 matlab.ui.control.UIAxes          
        Tab4                   matlab.ui.container.Tab            % Temp.
        UIAxes2                matlab.ui.control.UIAxes          
        Tab5                   matlab.ui.container.Tab            % Conc.
        UIAxes3                matlab.ui.control.UIAxes          
        Button3                matlab.ui.control.Button           % Generat...
    end


    properties (Access = private)
        ExcelFile % Description
    end

    methods (Access = private)
        
        function plot_RH(app,t,RH)
            %(ColumnVolume,dt,VolRate,Ptot,Tin,Tout,Thum,RHin,RHout)
            plot(app.UIAxes,t,RH);
            hold(app.UIAxes,'on');
            
            cycleTimes=str2num(app.EditField8.Value);
            if isempty(cycleTimes)
                cycleTimes=[0 t(end)];
            end
            plot(app.UIAxes,[cycleTimes(1);cycleTimes(1)],[0;app.UIAxes.YLim(end)],'--k');
            plot(app.UIAxes,[cycleTimes(2);cycleTimes(2)],[0;app.UIAxes.YLim(end)],'--k');
            
            app.UIAxes.XLabel.String='Time (min)';
            app.UIAxes.YLabel.String='RH (%)';
  
            hold(app.UIAxes,'off');
            app.UIAxes.XMinorTick='on';
            app.UIAxes.YLim(1)=round(min(RH)*0.9,0);
            app.UIAxes.YLim(2)=round(max(RH)*1.1,0);
        end

        function plot_Temp(app,t,Tin,Tout)
            plot(app.UIAxes2,t,Tin);
            hold(app.UIAxes2,'on');
            plot(app.UIAxes2,t,Tout);
            
            cycleTimes=str2num(app.EditField8.Value);
            if isempty(cycleTimes)
                cycleTimes=[0 t(end)];
            end
            plot(app.UIAxes2,[cycleTimes(1);cycleTimes(1)],[0;app.UIAxes2.YLim(end)],'--k');
            plot(app.UIAxes2,[cycleTimes(2);cycleTimes(2)],[0;app.UIAxes2.YLim(end)],'--k');
            
            app.UIAxes2.XLabel.String='Time (min)';
            app.UIAxes2.YLabel.String='T (℃)';
            
            hold(app.UIAxes2,'off');
            app.UIAxes2.XMinorTick='on';
            app.UIAxes2.YLim(1)=round(min(min(Tin),min(Tout))*0.9,0);
            app.UIAxes2.YLim(2)=round(max(max(Tin),max(Tout))*1.1,0);
            yLimits=[app.UIAxes2.YLim(1) app.UIAxes2.YLim(2)];
            xLimits=[app.UIAxes2.XLim(1) app.UIAxes2.XLim(2)];
            text(app.UIAxes2,0.42*((xLimits(2)-xLimits(1)))+xLimits(1),0.95*((yLimits(2)-yLimits(1)))+yLimits(1),['T_{out} - T_{in} = ' num2str(Tout(end) - Tin(end)) '^oC']);
        end

        function plot_Conc(app,t,Conc)
            plot(app.UIAxes3,t,Conc);
            app.UIAxes3.XLabel.String='Time (min)';
            app.UIAxes3.YLabel.String='y_w (mol/m^3)';
        end
        
        function cv=calc_columnVolume(app)
            if strcmp(app.DropDown13.Items{1},app.DropDown13.Value)
                cv=7.698e-6;
            elseif strcmp(app.DropDown13.Items{2},app.DropDown13.Value)
                cv=6.276e-5;
            end
        end
    end

    methods (Access = public)
        
        function [data,cycle,model] = get_data(app)
            % index.t=find(t>20,1,'first')+1
            xls=importdata(app.FilePathField.Value);  
            
            exprT=regexpi(app.FilePathField.Value,'\w*C ','match');
            cycle.Tregen=str2double(exprT{1}(3:end-2));
            exprRH=regexpi(app.FilePathField.Value,'RH\w*','match');
            cycle.RHin=str2double(exprRH{1}(4:end));
                   
            column.dt=double(app.DropDown2.Value)-64-1; 
            column.T1=double(app.DropDown3.Value)-64-1; 
            column.T3=double(app.DropDown5.Value)-64-1; 
            column.TH=double(app.DropDown6.Value)-64-1; 
            column.RHout=double(app.DropDown8.Value)-64-1;
            column.VDAin=double('J')-64-1;
            column.VMAin=double('K')-64-1;  
            
            data.t=((xls.data(:,column.dt))-repmat(xls.data(1,column.dt),length(column.dt),1))./60;
            
            % import set choice
            temp=app.ListBox.Value;
            temp2=regexp(temp,':','split');
            rng=str2num(temp2{1}):str2num(temp2{2});
            % end
            
            data.dt=diff([0;(xls.data(rng,column.dt))])./60;
            data.T1=(xls.data(rng,column.T1)); 
            data.T3=(xls.data(rng,column.T3));
            data.TH=(xls.data(rng,column.TH));
            data.RHout=(xls.data(rng,column.RHout));
            data.VDAin=(xls.data(rng,column.VDAin)); 
            data.VMAin=(xls.data(rng,column.VMAin)); 
            if strcmp(app.DropDown13.Items{1},app.DropDown13.Value)
                data.ColumnVolume=7.698e-6;
            elseif strcmp(app.DropDown13.Items{2},app.DropDown13.Value)
                data.ColumnVolume=6.276e-5;
            end
                      
            data.cycleTimes=str2num(app.EditField8.Value);
            
            i=1;
            for tCycle=data.cycleTimes
                ind(i)=find(data.t>tCycle,1,'first');
                i=i+1;
            end
            data.totalAds=app.NumericEditField5.Value;
            
            cycle.t=data.t(ind(1):ind(2));
            cycle.dt=diff((xls.data(ind(1):ind(2),column.dt)));
            cycle.dt=[cycle.dt; cycle.dt(1)];
            cycle.T1=(xls.data(ind(1):ind(2),column.T1)); 
            cycle.T3=(xls.data(ind(1):ind(2),column.T3));
            cycle.TH=(xls.data(ind(1):ind(2),column.TH));
            cycle.RHout=(xls.data(ind(1):ind(2),column.RHout));
            cycle.VDAin=(xls.data(ind(1):ind(2),column.VDAin)); 
            cycle.VMAin=(xls.data(ind(1):ind(2),column.VMAin)); 
            cycle.VolRate=cycle.VDAin+cycle.VMAin;
            cycle.Ptot=app.NumericEditField4.Value;
            
            app.NumericEditField2.Value=cycle.RHin;
            app.NumericEditField3.Value=mean(cycle.VolRate);
            
            model=app.DropDown14.Value;
        end

    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath('./src','./lib/coolprop');
        end

        % Button button pushed function
        function ButtonPushed(app)

            filterSpec={'*.xls;*xlsx;*.csv','Excel files (*.xls,*.xlsx,*.csv)'};

            [xlsName,pathName,~]=uigetfile(filterSpec,'Pick a file','MultiSelect','on');
            app.FilePathField.Value = [pathName xlsName];
            xls=importdata(app.FilePathField.Value);     
            
            column.dt=double(app.DropDown2.Value)-64-1; 
            column.T1=double(app.DropDown3.Value)-64-1; 
            column.T3=double(app.DropDown5.Value)-64-1; 
            column.TH=double(app.DropDown6.Value)-64-1; 
            column.RHout=double(app.DropDown8.Value)-64-1;          
            
            t=((xls.data(:,column.dt))-repmat(xls.data(1,column.dt),length(column.dt),1))./60;
            
            % capture set indices
            j=1; 
            count=0;
            for i=1:length(t)
                if i~=1 && t(i)<t(i-1)
                    ind(j)=i; 
                    j=j+1;
                else
                    count=count+1;
                end
            end
            if count==length(t)
                ind=length(t)+1;
            else 
                ind=[ind length(t)];
            end
            

            mem=1;
            for i=1:length(ind)
                ind2{i}=mem:ind(i)-1;
                mem=ind(i);
            end
            % end
            
            % display set options
            for i=1:length(ind2)
                app.ListBox.Items{i}=[num2str(ind2{i}(1)) ':' num2str(ind2{i}(end))];
            end
            % end
            
            %dt=diff([0;(xls.data(rng,column.dt))])./60;
            T1=(xls.data(:,column.T1))+273.15; 
            T3=(xls.data(:,column.T3))+273.15;
            %TH=(xls.data(rng,column.TH))+273.15;
            RHout=(xls.data(:,column.RHout));
            
            app.NumericEditField2.Value=0;
            app.NumericEditField5.Value=1;
            app.EditField8.Value=['0,' num2str(t(end-1))];
            
            plot_RH(app,t,RHout);
            plot_Temp(app,t,T1-273.15,T3-273.15);
            
        end

        % Button2 button pushed function
        function Button2Pushed(app)

            app.UIFigure.Name='TES Performance Calculator: Running...';
            drawnow;
           
            [data,cycle,model]=get_data(app);
            
            tes=humid_air_tes(data.ColumnVolume,cycle.dt(:),cycle.VolRate(:)./1000,cycle.Ptot,cycle.T1(:),cycle.T3(:),cycle.TH(:),cycle.RHin,cycle.RHout(:),model,app.FilePathField.Value);
            app.EditField2.Value=sprintf('%.4g',tes.EnergyDensity);
            app.DropDown9.Value=app.DropDown9.Items{1};
            app.EditField3.Value=sprintf('%.4g',tes.Heat);
            app.DropDown10.Value=app.DropDown10.Items{1};
            app.EditField4.Value=sprintf('%.4g',tes.AdsWater*1000);
            app.DropDown11.Value=app.DropDown11.Items{2};
            
            plot_RH(app,cycle.t,cycle.RHout);             
            plot_Temp(app,cycle.t,cycle.T1,cycle.T3); 
            
            app.UIFigure.Name='TES Performance Calculator';
            drawnow;
        end

        % DropDown9 value changed function
        function DropDown9ValueChanged(app, event)
            %value = app.DropDown9.Value;
            if strcmp(event.PreviousValue,app.DropDown9.Items{1})
                if strcmp(event.Value,app.DropDown9.Items{2})
                    app.EditField2.Value=sprintf('%.6g',str2double(app.EditField2.Value)*3600);
                elseif strcmp(event.Value,app.DropDown9.Items{3})
                    app.EditField2.Value=sprintf('%.6g',str2double(app.EditField2.Value)/0.00029307107017);
                end
            elseif strcmp(event.PreviousValue,app.DropDown9.Items{2})
                if strcmp(event.Value,app.DropDown9.Items{1})
                    app.EditField2.Value=sprintf('%.6g',str2double(app.EditField2.Value)/3600);
                elseif strcmp(event.Value,app.DropDown9.Items{3})
                    app.EditField2.Value=sprintf('%.6g',str2double(app.EditField2.Value)/1.05505585262);
                end
            elseif strcmp(event.PreviousValue,app.DropDown9.Items{3})
                if strcmp(event.Value,app.DropDown9.Items{1})
                    app.EditField2.Value=sprintf('%.6g',str2double(app.EditField2.Value)*0.00029307107017);
                elseif strcmp(event.Value,app.DropDown9.Items{2})
                    app.EditField2.Value=sprintf('%.6g',str2double(app.EditField2.Value)*1.05505585262);
                end
            end
        end

        % DropDown11 value changed function
        function DropDown11ValueChanged(app, event)
            %value = app.DropDown11.Value;
            if strcmp(event.PreviousValue,app.DropDown11.Items{1}) % kg
                if strcmp(event.Value,app.DropDown11.Items{2})
                    app.EditField4.Value=sprintf('%.6g',str2double(app.EditField4.Value)*1000);
                elseif strcmp(event.Value,app.DropDown11.Items{3})
                    app.EditField4.Value=sprintf('%.6g',str2double(app.EditField4.Value)*2.20462);
                end
            elseif strcmp(event.PreviousValue,app.DropDown11.Items{2}) % g
                if strcmp(event.Value,app.DropDown11.Items{1})
                    app.EditField4.Value=sprintf('%.6g',str2double(app.EditField4.Value)/1000);
                elseif strcmp(event.Value,app.DropDown11.Items{3})
                    app.EditField4.Value=sprintf('%.6g',str2double(app.EditField4.Value)*0.00220462);
                end
            elseif strcmp(event.PreviousValue,app.DropDown11.Items{3}) % lb
                if strcmp(event.Value,app.DropDown11.Items{1})
                    app.EditField4.Value=sprintf('%.6g',str2double(app.EditField4.Value)/2.20462);
                elseif strcmp(event.Value,app.DropDown11.Items{2})
                    app.EditField4.Value=sprintf('%.6g',str2double(app.EditField4.Value)/0.00220462);
                end
            end
        end

        % Button3 button pushed function
        function GenerateFiguresPushed(app)
            
            [data,cycle,model]=get_data(app);
            
            f=figure;
            f.Name=['T = ' num2str(cycle.Tregen) 'C, RH = ' num2str(cycle.RHin)];
            f.Position = [186 403 1065 319];
            
            fo.linewidth={'LineWidth',1.5};
            fo.fontweight={'FontWeight','normal'};
            fo.fontsize={'FontSize',12};
            fo.fontname={'FontName','Times New Roman'};
            
            cycle.startline={[data.cycleTimes(1);data.cycleTimes(1)],[0;200],'--k'};
            cycle.endline={[data.cycleTimes(2);data.cycleTimes(2)],[0;200],'--k'};
            
            %Plot RH
            s1=subplot(1,2,1);
            plot(s1,cycle.t,cycle.RHout,'-',fo.linewidth{:},'Color',[0    0.4470    0.7410]);
            hold(s1,'on');
            
            plot(s1,cycle.startline{:}); 
            plot(s1,cycle.endline{:});
            
            s1.XLabel.String='$ \mathrm{Time,}~t~\mathrm{(min)} $';
            s1.XLabel.Interpreter='latex';
            s1.YLabel.String='$ \mathrm{Relative~Humidity,}~RH~\mathrm{(\%)} $';
            s1.YLabel.Interpreter='latex';
            %set(s1.XLabel,fo.fontweight{:},fo.fontsize{:});
            %set(s1.YLabel,fo.fontweight{:},fo.fontsize{:});
            set(s1.XAxis,fo.fontsize{:},fo.fontweight{:},fo.fontname{:});
            set(s1.YAxis,fo.fontsize{:},fo.fontweight{:},fo.fontname{:});
  
            hold(s1,'off');
            s1.XMinorTick='on';
            s1.YLim(1)=round(min(cycle.RHout)*0.9,0);
            s1.YLim(2)=round(max(cycle.RHout)*1.1,0);
            s1.XLim(1)=round(min(cycle.t)*0.9,0);
            s1.XLim(2)=round(max(cycle.t)*1.1,0);    
            
            %Plot Temp
            s2=subplot(1,2,2);
            plot(s2,cycle.t,cycle.T1,'-',fo.linewidth{:},'Color',[0    0.4470    0.7410]);
            hold(s2,'on');
            plot(s2,cycle.t,cycle.T3,'-',fo.linewidth{:},'Color',[0.8500    0.3250    0.0980]);
            
            plot(s2,cycle.startline{:});
            plot(s2,cycle.endline{:});
            
            s2.XLabel.String='$ \mathrm{Time,}~t~\mathrm{(min)} $';
            s2.XLabel.Interpreter='latex';
            s2.YLabel.String='$ \mathrm{Temperature,}~T~\mathrm{(^oC)} $';
            s2.YLabel.Interpreter='latex';
            
            %set(s2.XLabel,fo.fontweight{:},fo.fontsize{:});
            %set(s2.YLabel,fo.fontweight{:},fo.fontsize{:});
            set(s2.XAxis,fo.fontsize{:},fo.fontweight{:},fo.fontname{:});
            set(s2.YAxis,fo.fontsize{:},fo.fontweight{:},fo.fontname{:});
  
            hold(s2,'off');
            s2.XMinorTick='on';
            s2.YLim(1)=round(min(min(cycle.T1),min(cycle.T3))*0.9,0);
            s2.YLim(2)=round(max(max(cycle.T1),max(cycle.T3))*1.1,0);
            s2.XLim(1)=round(min(cycle.t)*0.9,0);
            s2.XLim(2)=round(max(cycle.t)*1.1,0);   
            
            yLimits=[s2.YLim(1) s2.YLim(2)];
            xLimits=[s2.XLim(1) s2.XLim(2)];
            
            strResult2={['\it \rho_E \rm= ' num2str(app.EditField2.Value) ' ' app.DropDown9.Value], ... 
                ['\it H \rm= ' num2str(app.EditField3.Value) ' ' app.DropDown10.Value], ...
                ['\it m_{ads} \rm= ' num2str(app.EditField4.Value) ' ' app.DropDown11.Value]}; 
            
            strResult={['$ \rho_E = ' num2str(app.EditField2.Value) '~\mathrm{kWh/m^3} $'], ... 
                ['$ H = ' num2str(app.EditField3.Value) '~\mathrm{' app.DropDown10.Value '} $'], ...
                ['$ m_{ads} = ' num2str(app.EditField4.Value) '~\mathrm{' app.DropDown11.Value '} $']}; 
       
            text(s2, ...
                0.42*((xLimits(2)-xLimits(1)))+xLimits(1), ...
                0.85*((yLimits(2)-yLimits(1)))+yLimits(1), ...
                strResult, ...
                 'FontSize',14, ...
                 'FontName','Times New Roman', ...
                 'Interpreter','latex');
             
            % Adjust axes outerposition
            s1.OuterPosition = [0   0 0.5 1];
            s2.OuterPosition = [0.5 0 0.5 1];
             
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'TES Performance Calculator';
            setAutoResize(app, app.UIFigure, true)
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Excel spreadsheet column associations';
            app.Panel.Units = 'pixels';
            app.LabelDropDown = uilabel(app.Panel);
            app.LabelDropDown.Text = 'Time Stamp';
            app.DropDown1 = uidropdown(app.Panel);
            app.DropDown1.Items = {'A', 'B', 'C', 'D', 'E', 'F', 'G', 'H', 'I', 'J', 'K', 'L', 'M', 'N', 'O', 'P', 'Q', 'R', 'S'};
            app.DropDown1.Value = 'A';
            app.Label = uilabel(app.Panel);
            app.Label.Text = 'Time (secs)';
            app.DropDown2 = uidropdown(app.Panel);
            app.DropDown2.Items = {'A', 'B', 'C', 'D', 'E', 'F', 'G', 'H', 'I', 'J', 'K', 'L', 'M', 'N', 'O', 'P', 'Q', 'R', 'S'};
            app.DropDown2.Value = 'B';
            app.Label2 = uilabel(app.Panel);
            app.Label2.Text = 'T1 (°C)';
            app.DropDown3 = uidropdown(app.Panel);
            app.DropDown3.Items = {'A', 'B', 'C', 'D', 'E', 'F', 'G', 'H', 'I', 'J', 'K', 'L', 'M', 'N', 'O', 'P', 'Q', 'R', 'S'};
            app.DropDown3.Value = 'E';
            app.Label3 = uilabel(app.Panel);
            app.Label3.Text = 'T2 (°C)';
            app.DropDown4 = uidropdown(app.Panel);
            app.DropDown4.Items = {'A', 'B', 'C', 'D', 'E', 'F', 'G', 'H', 'I', 'J', 'K', 'L', 'M', 'N', 'O', 'P', 'Q', 'R', 'S'};
            app.DropDown4.Value = 'F';
            app.Label4 = uilabel(app.Panel);
            app.Label4.Text = 'T3 (°C)';
            app.DropDown5 = uidropdown(app.Panel);
            app.DropDown5.Items = {'A', 'B', 'C', 'D', 'E', 'F', 'G', 'H', 'I', 'J', 'K', 'L', 'M', 'N', 'O', 'P', 'Q', 'R', 'S'};
            app.DropDown5.Value = 'G';
            app.Label5 = uilabel(app.Panel);
            app.Label5.Text = 'TH (°C)';
            app.DropDown6 = uidropdown(app.Panel);
            app.DropDown6.Items = {'A', 'B', 'C', 'D', 'E', 'F', 'G', 'H', 'I', 'J', 'K', 'L', 'M', 'N', 'O', 'P', 'Q', 'R', 'S'};
            app.DropDown6.Value = 'D';
            app.Label6 = uilabel(app.Panel);
            app.Label6.Text = 'T4 (°C)';
            app.DropDown7 = uidropdown(app.Panel);
            app.DropDown7.Items = {'A', 'B', 'C', 'D', 'E', 'F', 'G', 'H', 'I', 'J', 'K', 'L', 'M', 'N', 'O', 'P', 'Q', 'R', 'S'};
            app.DropDown7.Value = 'H';
            app.Label7 = uilabel(app.Panel);
            app.Label7.Text = 'RH (%)';
            app.DropDown8 = uidropdown(app.Panel);
            app.DropDown8.Items = {'A', 'B', 'C', 'D', 'E', 'F', 'G', 'H', 'I', 'J', 'K', 'L', 'M', 'N', 'O', 'P', 'Q', 'R', 'S'};
            app.DropDown8.Value = 'C';
            app.LabelNumericEditField5 = uilabel(app.Panel);
            app.LabelNumericEditField5.Text = 'No. Ads. Cycles';
            app.NumericEditField5 = uieditfield(app.Panel, 'numeric');
            app.NumericEditField5.Value = 1;
            app.LabelEditField8 = uilabel(app.Panel);
            app.LabelEditField8.Text = 'Cycle Times (min):';
            app.EditField8 = uieditfield(app.Panel, 'text');
            app.Panel2 = uipanel(app.UIFigure);
            app.Panel2.Title = 'Input parameters';
            app.Panel2.Units = 'pixels';
            app.Label8 = uilabel(app.Panel2);
            app.Label8.Text = 'Inlet RH (%)';
            app.NumericEditField2 = uieditfield(app.Panel2, 'numeric');
            app.LabelEditField = uilabel(app.Panel2);
            app.LabelEditField.Text = 'Excel file';
            app.FilePathField = uieditfield(app.Panel2, 'text');
            app.Button = uibutton(app.Panel2, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed);
            app.Button.Text = 'Browse...';
            app.Label14 = uilabel(app.Panel2);
            app.Label14.Text = 'Volume flow (LPM)';
            app.NumericEditField3 = uieditfield(app.Panel2, 'numeric');
            app.NumericEditField3.Value = 24;
            app.Label15 = uilabel(app.Panel2);
            app.Label15.Text = 'Pressure (Pa)';
            app.NumericEditField4 = uieditfield(app.Panel2, 'numeric');
            app.NumericEditField4.Value = 101.325;
            app.LabelDropDown13 = uilabel(app.Panel2);
            app.LabelDropDown13.Text = 'Column volume (m³)';
            app.DropDown13 = uidropdown(app.Panel2);
            app.DropDown13.Items = {'7.698×10⁻⁶', '6.276×10⁻⁵'};
            app.DropDown13.Value = '6.276×10⁻⁵';
            app.Label17 = uilabel(app.Panel2);
            app.Label17.Text = 'Model option';
            app.DropDown14 = uidropdown(app.Panel2);
            app.DropDown14.Items = {'Old', 'New'};
            app.DropDown14.Value = 'New';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.Units = 'pixels';
            app.Tab = uitab(app.TabGroup);
            app.Tab.Units = 'pixels';
            app.Tab.Title = 'Performance';
            app.DropDown9 = uidropdown(app.Tab);
            app.DropDown9.Items = {'kWh/m³', 'kJ/m³', 'Btu/m³'};
            app.DropDown9.ValueChangedFcn = createCallbackFcn(app, @DropDown9ValueChanged, true);
            app.DropDown9.Value = 'kWh/m³';
            app.DropDown10 = uidropdown(app.Tab);
            app.DropDown10.Items = {'kJ', 'kWh', 'Btu'};
            app.DropDown10.Value = 'kJ';
            app.DropDown11 = uidropdown(app.Tab);
            app.DropDown11.Items = {'kg', 'g', 'lb'};
            app.DropDown11.ValueChangedFcn = createCallbackFcn(app, @DropDown11ValueChanged, true);
            app.DropDown11.Value = 'g';
            app.LabelEditField2 = uilabel(app.Tab);
            app.LabelEditField2.Text = 'E.D.';
            app.EditField2 = uieditfield(app.Tab, 'text');
            app.EditField2.Editable = 'off';
            app.Label9 = uilabel(app.Tab);
            app.Label9.Text = 'Heat';
            app.EditField3 = uieditfield(app.Tab, 'text');
            app.EditField3.Editable = 'off';
            app.Label10 = uilabel(app.Tab);
            app.Label10.Text = 'H₂O ads.';
            app.EditField4 = uieditfield(app.Tab, 'text');
            app.EditField4.Editable = 'off';
            app.Label16 = uilabel(app.Tab);
            app.Label16.Text = 'Ads. Cycle';
            app.DropDown12 = uidropdown(app.Tab);
            app.DropDown12.Items = {'1', '2', '3', '4', '5'};
            app.DropDown12.Value = '1';
            app.LabelListBox = uilabel(app.Tab);
            app.LabelListBox.Text = 'Data Set';
            app.ListBox = uilistbox(app.Tab);
            app.ListBox.Items = {};
            app.ListBox.Value = {};
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Units = 'pixels';
            app.Tab2.Title = 'Properties';
            app.Label11 = uilabel(app.Tab2);
            app.Label11.Text = 'Cp';
            app.EditField5 = uieditfield(app.Tab2, 'text');
            app.EditField5.Editable = 'off';
            app.Label12 = uilabel(app.Tab2);
            app.Label12.Text = 'Heat';
            app.EditField6 = uieditfield(app.Tab2, 'text');
            app.EditField6.Editable = 'off';
            app.Label13 = uilabel(app.Tab2);
            app.Label13.Text = 'H₂O ads.';
            app.EditField7 = uieditfield(app.Tab2, 'text');
            app.EditField7.Editable = 'off';
            app.Button2 = uibutton(app.UIFigure, 'push');
            app.Button2.ButtonPushedFcn = createCallbackFcn(app, @Button2Pushed);
            app.Button2.Text = 'Calculate';
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.TabGroup2.Units = 'pixels';
            app.Tab3 = uitab(app.TabGroup2);
            app.Tab3.Units = 'pixels';
            app.Tab3.Title = 'RH';
            app.UIAxes = uiaxes(app.Tab3);
            xlabel(app.UIAxes, 'Time (min)');
            ylabel(app.UIAxes, 'RH (%)');
            app.Tab4 = uitab(app.TabGroup2);
            app.Tab4.Units = 'pixels';
            app.Tab4.Title = 'Temp.';
            app.UIAxes2 = uiaxes(app.Tab4);
            xlabel(app.UIAxes2, 'Time (min)');
            ylabel(app.UIAxes2, 'T (℃)');
            app.Tab5 = uitab(app.TabGroup2);
            app.Tab5.Units = 'pixels';
            app.Tab5.Title = 'Conc.';
            app.UIAxes3 = uiaxes(app.Tab5);
            xlabel(app.UIAxes3, 'Time (min)');
            ylabel(app.UIAxes3, 'y_w (mol/m^3)');
            app.Button3 = uibutton(app.UIFigure, 'push');
            app.Button3.ButtonPushedFcn = createCallbackFcn(app, @GenerateFiguresPushed);
            app.Button3.Text = 'Generate Figures';
        end
    end

    methods (Access = public)

        % Construct app
        function app = tes_performance_calculator()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end